classdef DobotTicTacToeGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        JogPanel                      matlab.ui.container.Panel
        xSliderLabel                  matlab.ui.control.Label
        xSlider                       matlab.ui.control.Slider
        ySliderLabel                  matlab.ui.control.Label
        ySlider                       matlab.ui.control.Slider
        zSliderLabel                  matlab.ui.control.Label
        zSlider                       matlab.ui.control.Slider
        EnablejoggingCheckBox         matlab.ui.control.CheckBox
        TeachPanel                    matlab.ui.container.Panel
        ShoulderSliderLabel           matlab.ui.control.Label
        ShoulderSlider                matlab.ui.control.Slider
        ElbowSliderLabel              matlab.ui.control.Label
        ElbowSlider                   matlab.ui.control.Slider
        EndSliderLabel                matlab.ui.control.Label
        EndSlider                     matlab.ui.control.Slider
        BaseSliderLabel               matlab.ui.control.Label
        BaseSlider                    matlab.ui.control.Slider
        SuctioncupCheckBox            matlab.ui.control.CheckBox
        DobotTicTacToeLabel           matlab.ui.control.Label
        SafetysymbolPanel             matlab.ui.container.Panel
        DistancefromfrontSlider       matlab.ui.control.Slider
        DistancefromfrontSliderLabel  matlab.ui.control.Label
        SimulatesafetysymbolButton    matlab.ui.control.StateButton
        GamePanel                     matlab.ui.container.Panel
        StartgameButton               matlab.ui.control.Button
        YourturnLampLabel             matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        ResumeButton                  matlab.ui.control.Button
        ESTOPButton                   matlab.ui.control.StateButton
        UserealrobotCheckBox          matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        game;
    end
    
    methods (Access = private)
        
        function value = snapSlider(~, slider)
            value = 2*round(slider.Value/2,1);
            slider.Value = value;
        end
        
        function v = readJogSliders(app)
            % read jog sliders and snap to increments
            v(1) = snapSlider(app, app.xSlider);
            v(2) = snapSlider(app, app.ySlider);
            v(3) = snapSlider(app, app.zSlider);
        end
        
        function q = readTeachSliders(app)
            q = zeros(1,4);
            q(1) = app.BaseSlider.Value;
            q(2) = app.ShoulderSlider.Value;
            q(3) = app.ElbowSlider.Value;
            q(4) = app.EndSlider.Value;
        end
        
        function setTeachSliders(app,q)
            app.BaseSlider.Value = q(1);
            app.ShoulderSlider.Value = q(2);
            app.ElbowSlider.Value = q(3);
            app.EndSlider.Value = q(4);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try % try to start with real robot preference, otherwise start simulated
                app.game = DobotTicTacToe(app.UserealrobotCheckBox.Value);
                if app.UserealrobotCheckBox.Value
                    app.game.dobot.PublishTargetJoint(app.game.dobot.qn);
                end
            catch
                app.game = DobotTicTacToe(false);
                app.UserealrobotCheckBox.Value = false;
            end
            
            app.game.dobot.Plot(app.game.dobot.qn);
            
            % set teach limits
            app.BaseSlider.Limits = app.game.dobot.qlimReal(1,:);
            app.ShoulderSlider.Limits = app.game.dobot.qlimReal(2,:);
            app.ElbowSlider.Limits = app.game.dobot.qlimReal(3,:);
            app.EndSlider.Limits = app.game.dobot.qlimReal(4,:);
            
            % set teach values
            setTeachSliders(app,app.game.dobot.qn);
        end

        % Value changed function: ESTOPButton
        function ESTOPButtonValueChanged(app, event)
            value = app.ESTOPButton.Value;
            % state button
            % if pressed, stop, store which player
            % if unpressed, allow resuming
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            % if pressed and estop is pushed, do nothing
            % else resume
            % run resume function: view board, update state in game class, 
            % play game starting from stored player
        end

        % Button pushed function: StartgameButton
        function StartgameButtonPushed(app, event)
            % run game function
            app.game.PlayGame(1);
        end

        % Value changed function: BaseSlider, ElbowSlider, 
        % EndSlider, ShoulderSlider
        function TeachSliderValueChanged(app, event)
            q = readTeachSliders(app);
            app.game.dobot.Animate(q);
            if app.UserealrobotCheckBox.Value
                app.game.dobot.PublishTargetJoint(q);
            end
        end

        % Value changed function: UserealrobotCheckBox
        function UserealrobotCheckBoxValueChanged(app, event)
            app.startupFcn(); % restart app with preferences
        end

        % Value changed function: SuctioncupCheckBox
        function SuctioncupCheckBoxValueChanged(app, event)
            value = app.SuctioncupCheckBox.Value;
            if app.UserealrobotCheckBox.Value
                app.game.dobot.PublishToolState(uint8(value));
            end
        end

        % Value changed function: EnablejoggingCheckBox
        function EnablejoggingCheckBoxValueChanged(app, event)
            fps = 1;
            maxSpeed = 0.02;
            q = app.game.dobot.GetPos();
            while app.EnablejoggingCheckBox.Value
                tic;
                v = readJogSliders(app);
                q = app.game.manual(v,q,maxSpeed/fps);
                app.setTeachSliders(q);
                if app.UserealrobotCheckBox.Value
                    app.game.dobot.PublishTargetJoint(q);
                end
%                 app.game.dobot.Animate(q);
                elapsed = toc;
                
                if elapsed < 1/fps
                    pause(1/fps - elapsed);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.JogPanel = uipanel(app.UIFigure);
            app.JogPanel.Title = 'Jog';
            app.xSliderLabel = uilabel(app.JogPanel);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.JogPanel);
            app.xSlider.Limits = [-1 1];
            app.ySliderLabel = uilabel(app.JogPanel);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.JogPanel);
            app.ySlider.Limits = [-1 1];
            app.zSliderLabel = uilabel(app.JogPanel);
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.JogPanel);
            app.zSlider.Limits = [-1 1];
            app.EnablejoggingCheckBox = uicheckbox(app.JogPanel);
            app.EnablejoggingCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnablejoggingCheckBoxValueChanged, true);
            app.EnablejoggingCheckBox.Text = 'Enable jogging';
            app.TeachPanel = uipanel(app.UIFigure);
            app.TeachPanel.Title = 'Teach';
            app.ShoulderSliderLabel = uilabel(app.TeachPanel);
            app.ShoulderSliderLabel.Text = 'Shoulder';
            app.ShoulderSlider = uislider(app.TeachPanel);
            app.ShoulderSlider.Limits = [-1 1];
            app.ShoulderSlider.ValueChangedFcn = createCallbackFcn(app, @TeachSliderValueChanged, true);
            app.ElbowSliderLabel = uilabel(app.TeachPanel);
            app.ElbowSliderLabel.Text = 'Elbow';
            app.ElbowSlider = uislider(app.TeachPanel);
            app.ElbowSlider.Limits = [-1 1];
            app.ElbowSlider.ValueChangedFcn = createCallbackFcn(app, @TeachSliderValueChanged, true);
            app.EndSliderLabel = uilabel(app.TeachPanel);
            app.EndSliderLabel.Text = 'End';
            app.EndSlider = uislider(app.TeachPanel);
            app.EndSlider.Limits = [-1 1];
            app.EndSlider.ValueChangedFcn = createCallbackFcn(app, @TeachSliderValueChanged, true);
            app.BaseSliderLabel = uilabel(app.TeachPanel);
            app.BaseSliderLabel.Text = 'Base';
            app.BaseSlider = uislider(app.TeachPanel);
            app.BaseSlider.Limits = [-1 1];
            app.BaseSlider.ValueChangedFcn = createCallbackFcn(app, @TeachSliderValueChanged, true);
            app.SuctioncupCheckBox = uicheckbox(app.TeachPanel);
            app.SuctioncupCheckBox.ValueChangedFcn = createCallbackFcn(app, @SuctioncupCheckBoxValueChanged, true);
            app.SuctioncupCheckBox.Text = 'Suction cup';
            app.DobotTicTacToeLabel = uilabel(app.UIFigure);
            app.DobotTicTacToeLabel.Text = 'Dobot Tic Tac Toe';
            app.SafetysymbolPanel = uipanel(app.UIFigure);
            app.SafetysymbolPanel.Title = 'Safety symbol';
            app.DistancefromfrontSlider = uislider(app.SafetysymbolPanel);
            app.DistancefromfrontSliderLabel = uilabel(app.SafetysymbolPanel);
            app.DistancefromfrontSliderLabel.Text = 'Distance from front';
            app.SimulatesafetysymbolButton = uibutton(app.SafetysymbolPanel, 'state');
            app.SimulatesafetysymbolButton.Text = 'Simulate safety symbol';
            app.GamePanel = uipanel(app.UIFigure);
            app.GamePanel.Title = 'Game';
            app.StartgameButton = uibutton(app.GamePanel, 'push');
            app.StartgameButton.ButtonPushedFcn = createCallbackFcn(app, @StartgameButtonPushed, true);
            app.StartgameButton.Text = 'Start game';
            app.YourturnLampLabel = uilabel(app.GamePanel);
            app.YourturnLampLabel.Text = 'Your turn';
            app.StatusLamp = uilamp(app.GamePanel);
            app.ResumeButton = uibutton(app.GamePanel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.ESTOPButton = uibutton(app.GamePanel, 'state');
            app.ESTOPButton.ValueChangedFcn = createCallbackFcn(app, @ESTOPButtonValueChanged, true);
            app.ESTOPButton.Text = 'ESTOP';
            app.UserealrobotCheckBox = uicheckbox(app.UIFigure);
            app.UserealrobotCheckBox.ValueChangedFcn = createCallbackFcn(app, @UserealrobotCheckBoxValueChanged, true);
            app.UserealrobotCheckBox.Text = 'Use real robot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DobotTicTacToeGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end